classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CalculateButton                matlab.ui.control.Button
        AboveForBatteryYouNeedLabel    matlab.ui.control.Label
        StoragelocationEditField       matlab.ui.control.EditField
        StoragelocationEditFieldLabel  matlab.ui.control.Label
        EditField_9                    matlab.ui.control.NumericEditField
        BatteryPriceLabel              matlab.ui.control.Label
        EditField_8                    matlab.ui.control.NumericEditField
        EditField_7                    matlab.ui.control.NumericEditField
        EditField_6                    matlab.ui.control.NumericEditField
        EditField_5                    matlab.ui.control.NumericEditField
        BasiccalculaterateLabel        matlab.ui.control.Label
        TargetEnergyWhLabel            matlab.ui.control.Label
        TargetSeriesSLabel             matlab.ui.control.Label
        TargetPowerWLabel              matlab.ui.control.Label
        EditField_3                    matlab.ui.control.NumericEditField
        EditField_2                    matlab.ui.control.NumericEditField
        EditField                      matlab.ui.control.NumericEditField
        AboveForBatteryYouHaveLabel    matlab.ui.control.Label
        BatteryCalculatorByNomanLabel  matlab.ui.control.Label
        BatteryEnergyWhLabel           matlab.ui.control.Label
        BatterySeriesSLabel            matlab.ui.control.Label
        BatteryPowerWLabel             matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function [num_batteries, total_energy, total_price] = calculate_battery_count(~, target_P, target_S, target_Wh, battery_P, battery_S, battery_Wh, battery_price, Basic_rate)
            % 计算每个电池串联数量
            num_series = floor(target_P / (battery_P * battery_S));
            % 计算每个电池并联数量
            num_parallel = floor(target_S / battery_S);
            % 计算总电池数量
            num_batteries = num_series * num_parallel;
            % 计算总能量
            total_energy = num_batteries * battery_Wh;
            % 计算总价格
            total_price = num_batteries * battery_price * Basic_rate;
            % 调整电池数量，使总能量最接近目标能量
            while total_energy < target_Wh
                num_batteries = num_batteries + 1;
                total_energy = num_batteries * battery_Wh;
                total_price = num_batteries * battery_price;
            end
        end

        function CalculateButtonPushed(app, ~)
            % 获取用户输入
            % 获取用户输入
            battery_P = app.EditField.Value;
            battery_S = app.EditField2.Value;
            battery_Wh = app.EditField3.Value;
            battery_price = app.EditField9.Value;
            target_P = app.EditField5.Value;
            target_S = app.EditField6.Value;
            target_Wh = app.EditField7.Value;
            Basic_rate = app.EditField8.Value;
            storage_location = app.StoragelocationEditField.Value;

            % 调用计算函数
            [num_batteries, total_energy, total_price] = app.calculate_battery_count(target_P, target_S, target_Wh, battery_P, battery_S, battery_Wh, battery_price, Basic_rate);

            % 显示结果
            result = sprintf('电池数量: %d\n总能量（Wh）: %d\n总价格: $%.2f\n', num_batteries, total_energy, total_price);
            app.ResultTextArea.Value = result;

            % 检查存储位置是否存在，如果不存在则创建
            if ~isfolder(storage_location)
                mkdir(storage_location);
            end

            % 确定文件路径和文件名
            base_file_name = 'output_battery.txt';
            file_path = fullfile(storage_location, base_file_name);

            % 如果文件已存在，添加后缀
            if exist(file_path, 'file')
                [file_path, new_file_name] = generate_unique_filename(storage_location, base_file_name);
            else
                new_file_name = base_file_name;
            end

            % 输出结果到文件
            fileID = fopen(file_path, 'w');
            if fileID == -1
                uialert(app.UIFigure, '无法创建输出文件，请检查存储路径的权限。', '错误');
                return;
            end
            fprintf(fileID, result);
            fclose(fileID);
            uialert(app.UIFigure, ['计算结果已保存到 ', new_file_name], '成功');
        end

        function [file_path, new_file_name] = generate_unique_filename(~, folder, base_file_name)
            % 生成唯一文件名，如果文件已存在
            [pathstr, name, ext] = fileparts(base_file_name);
            counter = 1;
            new_file_name = sprintf('%s_%d%s', name, counter, ext);
            file_path = fullfile(folder, new_file_name);
            while exist(file_path, 'file')
                counter = counter + 1;
                new_file_name = sprintf('%s_%d%s', name, counter, ext);
                file_path = fullfile(folder, new_file_name);
            end
        end
    end
    

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BatteryPowerWLabel = uilabel(app.UIFigure);
            app.BatteryPowerWLabel.Text = 'Battery Power (W)';
            app.BatterySeriesSLabel = uilabel(app.UIFigure);
            app.BatterySeriesSLabel.Text = 'Battery Series（S）';
            app.BatteryEnergyWhLabel = uilabel(app.UIFigure);
            app.BatteryEnergyWhLabel.Text = 'Battery Energy (Wh)';
            app.BatteryCalculatorByNomanLabel = uilabel(app.UIFigure);
            app.BatteryCalculatorByNomanLabel.Text = 'Battery Calculator By Noman';
            app.AboveForBatteryYouHaveLabel = uilabel(app.UIFigure);
            app.AboveForBatteryYouHaveLabel.Text = 'Above For Battery You Have';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.TargetPowerWLabel = uilabel(app.UIFigure);
            app.TargetPowerWLabel.Text = 'Target Power (W)';
            app.TargetSeriesSLabel = uilabel(app.UIFigure);
            app.TargetSeriesSLabel.Text = 'Target Series（S）';
            app.TargetEnergyWhLabel = uilabel(app.UIFigure);
            app.TargetEnergyWhLabel.Text = 'Target Energy (Wh)';
            app.BasiccalculaterateLabel = uilabel(app.UIFigure);
            app.BasiccalculaterateLabel.Text = 'Basic calculate rate';
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.BatteryPriceLabel = uilabel(app.UIFigure);
            app.BatteryPriceLabel.Text = 'Battery Price ($)';
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.StoragelocationEditFieldLabel = uilabel(app.UIFigure);
            app.StoragelocationEditFieldLabel.Text = 'Storage location';
            app.StoragelocationEditField = uieditfield(app.UIFigure, 'text');
            app.AboveForBatteryYouNeedLabel = uilabel(app.UIFigure);
            app.AboveForBatteryYouNeedLabel.Text = 'Above For Battery You Need';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.Text = 'Calculate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end